classdef GaitUserID < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        LoadDataTab                     matlab.ui.container.Tab
        LoadDataGridLayout              matlab.ui.container.GridLayout
        LoadDataPlotGridLayout          matlab.ui.container.GridLayout
        PlotDataGridLayout              matlab.ui.container.GridLayout
        ViewButtonGridLayout            matlab.ui.container.GridLayout
        CombineAxesButton               matlab.ui.control.StateButton
        SpeedAxesButton                 matlab.ui.control.StateButton
        SpectrogramAxesButton           matlab.ui.control.StateButton
        SpeedDetailGridLayout           matlab.ui.container.GridLayout
        LegAverage                      matlab.ui.control.Label
        TorsoAverage                    matlab.ui.control.Label
        MovementAverage                 matlab.ui.control.Label
        LegMin                          matlab.ui.control.Label
        TorsoMin                        matlab.ui.control.Label
        MovementMin                     matlab.ui.control.Label
        LegMax                          matlab.ui.control.Label
        TorsoMax                        matlab.ui.control.Label
        MovementMax                     matlab.ui.control.Label
        LegSpeedLabel                   matlab.ui.control.Label
        MovementSpeedLabel              matlab.ui.control.Label
        LegMaxLabel                     matlab.ui.control.Label
        MovementAverageLabel            matlab.ui.control.Label
        MovementMinLabel                matlab.ui.control.Label
        MovementMaxLabel                matlab.ui.control.Label
        TorsoMinLabel                   matlab.ui.control.Label
        LegAverageLabel                 matlab.ui.control.Label
        TorsoSpeedLabel                 matlab.ui.control.Label
        LegMinLabel                     matlab.ui.control.Label
        TorsoAverageLabel               matlab.ui.control.Label
        TorsoMaxLabel                   matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        TabLoadDataGridLayout           matlab.ui.container.GridLayout
        PredictGridLayout               matlab.ui.container.GridLayout
        SelectedModelEditField          matlab.ui.control.EditField
        SelectUseModelButton            matlab.ui.control.Button
        PredictLabel                    matlab.ui.control.Label
        PeriodPanel                     matlab.ui.container.Panel
        PeriodGridLayout                matlab.ui.container.GridLayout
        PeriodDiffEditField             matlab.ui.control.NumericEditField
        PeriodDiffLabel                 matlab.ui.control.Label
        PeriodLabel                     matlab.ui.control.Label
        PeriodEditField                 matlab.ui.control.NumericEditField
        PeriodButton                    matlab.ui.control.Button
        PacketDetailPanel               matlab.ui.container.Panel
        PacketDetailGridLayout          matlab.ui.container.GridLayout
        SampleRateEditField             matlab.ui.control.NumericEditField
        TxAntennaEditField              matlab.ui.control.NumericEditField
        RxAntennaEditField              matlab.ui.control.NumericEditField
        SamplerateEditFieldLabel        matlab.ui.control.Label
        TxAntennaEditFieldLabel         matlab.ui.control.Label
        RxAntennaEditFieldLabel         matlab.ui.control.Label
        FileLoadPanel                   matlab.ui.container.Panel
        FileLoadGridLayout              matlab.ui.container.GridLayout
        FileChosenGridLayout            matlab.ui.container.GridLayout
        FilenameEditField               matlab.ui.control.EditField
        SelectFileButton                matlab.ui.control.Button
        LoadFileButton                  matlab.ui.control.Button
        TrainModelTab                   matlab.ui.container.Tab
        ImportDataGridLayout            matlab.ui.container.GridLayout
        ImportDataPlotGridLayout        matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        UserDataTab                     matlab.ui.container.Tab
        UserDataTabGridLayout           matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        TrainTestIndicatorGridLayout    matlab.ui.container.GridLayout
        TestROCPushButton               matlab.ui.control.Button
        TrainROCPushButton              matlab.ui.control.Button
        TestConfusionPushButton         matlab.ui.control.Button
        TrainConfusionPushButton        matlab.ui.control.Button
        TestAccNum                      matlab.ui.control.Label
        TrainAccNum                     matlab.ui.control.Label
        TrainIndicatorLabel             matlab.ui.control.Label
        TrainROCLabel                   matlab.ui.control.Label
        TrainConfusionLabel             matlab.ui.control.Label
        TrainAccLabel                   matlab.ui.control.Label
        TestConfusionLabel              matlab.ui.control.Label
        TestIndicatorLabel              matlab.ui.control.Label
        TestROCLabel                    matlab.ui.control.Label
        TestAccLabel                    matlab.ui.control.Label
        TabLoadDataGridLayout_2         matlab.ui.container.GridLayout
        ImportasUserLabel               matlab.ui.control.Label
        TrainDetailPanel                matlab.ui.container.Panel
        TrainDetailPeriodGridLayout     matlab.ui.container.GridLayout
        SaveModelButton                 matlab.ui.control.Button
        LeaveoutEditFiled               matlab.ui.control.NumericEditField
        LeaveoutLabel                   matlab.ui.control.Label
        KFoldLabel                      matlab.ui.control.Label
        KFoldEditField                  matlab.ui.control.NumericEditField
        TrainStartButton                matlab.ui.control.Button
        ImportPacketDetailPanel         matlab.ui.container.Panel
        ImportPacketDetailGridLayout    matlab.ui.container.GridLayout
        ImportSampleRateEditField       matlab.ui.control.NumericEditField
        ImportTxAntennaEditField        matlab.ui.control.NumericEditField
        ImportRxAntennaEditField        matlab.ui.control.NumericEditField
        ImportSamplerateEditFieldLabel  matlab.ui.control.Label
        ImportTxAntennaEditFieldLabel   matlab.ui.control.Label
        ImportRxAntennaEditFieldLabel   matlab.ui.control.Label
        DirLoadPanel                    matlab.ui.container.Panel
        DirLoadGridLayout               matlab.ui.container.GridLayout
        DirChosenGridLayout             matlab.ui.container.GridLayout
        DirNameEditField                matlab.ui.control.EditField
        SelectDirButton                 matlab.ui.control.Button
        LoadDirButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        Filename % CSI filename
        Tx % num of Tx antenna
        Rx % num of Rx antenna
        Subcarriers % num of subcarriers
        CSIData % Description
        SpectrogramAxesDict % Description
        AxesButtonStatus % Description
        SpeedAxesDict % Description
        WaveLength % Description
        SampleRate % Description
        RenewSpeedFlag % Description
        RenewSpectrogramFlag
        PCAStart % Description
        PCAEnd
        DirFiles % Description
        IsPeriodDetected % Description
        SelectedDirs % Description
        SelectedUserlabels % Description
        ResultMat % Description
        Classes % Description
%         TrainROCObjects % Description
        TrainResult
        TestResult
        TrainedClassifier % Description

        TrainData % Description
        TestData

        LoadedModel
        ModelFilename % Description
        IsModelSelected % Description
    end
    
    methods (Access = private)
        
        function popupErrorWindow(~, errMsg) % for a resized error window
            mh = msgbox(errMsg, 'Error', 'modal');
            th = findall(mh, 'Type', 'Text');
            bh = findall(mh, 'Tag', 'OKButton');
                
%             th.FontSize = 10;
%             deltaWidth = sum(th.Extent([1,3])) - mh.Position(3) + abs(th.Extent(1));
%             deltaHeight = sum(th.Extent([2,4])) - mh.Position(4) + 10;
%             mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
%             bh.Position([3,4]) = bh.Position([3,4]) + [deltaWidth, deltaHeight];
            uiwait(mh);
        end
        
        function generateSpectrogram(app)
            % TODO remove receivers num
            % TODO move (wave_length sample_rate pca_num) outside
            app.WaveLength = 299792458 / 5.825e9;
            app.SampleRate = 1000;
            pca_num = 20;
            [doppler_spectrum, freq_axis_bin, velocity_axis_bin,~,~] = generate_doppler_spectrum(app.Filename, 1, app.Rx, ...
                                    app.Subcarriers, 'stft', app.WaveLength, app.SampleRate, pca_num);
            % flip negative spectrogram to positive
            [~,idx] = max(freq_axis_bin);
            circle_length = length(freq_axis_bin) - idx;
            %     doppler_spectrum_noflip = circshift(doppler_spectrum, [0 0 circle_length 0]);
            doppler_spectrum = circshift(doppler_spectrum, [0 0 circle_length 0]);
            freq_axis_bin=circshift(freq_axis_bin, [0 circle_length]);
            velocity_axis_bin=circshift(velocity_axis_bin, [0 circle_length]);
            
            %% flip negative values to positive
        %     doppler_spectrum = zeros(size(doppler_spectrum, 1), size(doppler_spectrum, 2), ...
        %                              (size(doppler_spectrum, 3)-1)/2+1, ... % 除去0以外频率的二分之一+频率0
        %                              size(doppler_spectrum, 4));
        %     zero_freq_bin_i = median(1:length(freq_axis_bin)); % 0频率在freq_axis_bin中的index
        %     for c=1:20 % pca compnt
        %         for i=1:zero_freq_bin_i
        %             doppler_spectrum(1,c,i,:) = (doppler_spectrum_noflip(1,c,zero_freq_bin_i+i-1,:) ...
        %                                        + doppler_spectrum_noflip(1,c,zero_freq_bin_i-i+1,:)) / 2;
        %         end
            %     end       
            
            % TODO move upperstop
            upper_stop = 100;
%             colormap(jet);
            doppler_sum = zeros(size(doppler_spectrum));
        
%             app.PCAStart = app.PCAStartEditField.Value;
%             app.PCAEnd = app.PCAEndEditField.Value;
            for i=1:1 % pca compnt
                % filter
                mdlSpecLpf = fspecial('gaussian', 5, 0.8); % Build filter
                outSpec = imfilter(doppler_spectrum(1,i,:,:),mdlSpecLpf); % Smoothing
                doppler_sum(1,1,:,:) = doppler_sum(1,1,:,:) + outSpec;
            end
            app.SpectrogramAxesDict('Spectrogram') = squeeze(doppler_sum(1,1,:,:));
            app.SpectrogramAxesDict('X') = 1:size(doppler_spectrum, 4);
            app.SpectrogramAxesDict('Y') = 0:upper_stop;
            
            app.RenewSpectrogramFlag = false;
        end
        
        function updateUIAxes(app, axesName)
            
            switch axesName
                
                case {'Spectrogram', 'SpectrogramButton'}
                    if app.RenewSpectrogramFlag % no available spectrogram
                        generateSpectrogram(app);
                    end
                    % change gridlayout, increase spectrogram area
%                     app.UserDataTabGridLayout.RowHeight = {'2.39x', '1.12x'};
%                     app.GridLayout3.RowHeight = {'0x', '2.27x'};
                    enable_group = findall(app.SpeedDetailGridLayout, 'Enable', 'on');
                    set(enable_group, 'Enable', 'off');

                    X = app.SpectrogramAxesDict('X');
                    Y = app.SpectrogramAxesDict('Y');
                    spectrogram = app.SpectrogramAxesDict('Spectrogram');
                    
                    cla(app.UIAxes, 'reset');
                    hold(app.UIAxes, 'on');

                    app.UIAxes.XLim = [1, length(X)];
                    app.UIAxes.YLim = [0, Y(end)];
                    app.UIAxes.XLabel.String = 'Time (ms)';
                    app.UIAxes.YLabel.String = 'Frequency (Hz)';
                    app.UIAxes.Title.String = 'Spectrogram';
                    
%                     enable_group = findall(app.PeriodPanel, 'Enable', 'on');
%                     set(enable_group, 'Enable', 'off');
%                     app.PeriodButton.Enable = 'on';
%                     app.PeriodButton.Visible = false;
%                     app.PeriodEditField.Visible = false;
                    
                    mesh(app.UIAxes, X, Y, spectrogram);
                    
                case {'Speed', 'SpeedButton'}
                    if app.RenewSpeedFlag % no available speed
                        generateSpeeds(app);
                        updateSpeedDetail(app);
                    end

                    % change gridlayout, reduce spectrogram area
%                     app.SpeedDetailGridLayout.Visible = true;
%                     app.UserDataTabGridLayout.RowHeight = {'1.5x','1.12x'};
%                     app.GridLayout3.RowHeight = {'2x', '1x'};
                    enable_group = findall(app.SpeedDetailGridLayout, 'Enable', 'off');
                    set(enable_group, 'Enable', 'on');

                    X = app.SpeedAxesDict('X');
                    Y = app.SpeedAxesDict('Y');
                    movementSpeed = app.SpeedAxesDict('MovementSpeed');
                    torsoSpeed = app.SpeedAxesDict('TorsoSpeed');
                    legSpeed = app.SpeedAxesDict('LegSpeed');
                    
                    cla(app.UIAxes, 'reset');
                    hold(app.UIAxes, 'on');

                    app.UIAxes.XLim = [1, length(X)];
                    app.UIAxes.YLim = [0, Y(end)];
                    app.UIAxes.XLabel.String = 'Time (ms)';
                    app.UIAxes.YLabel.String = 'Speed (m/s)';
                    app.UIAxes.Title.String = 'Speed';
                    
                    visible_group = findall(app.PeriodPanel, 'Visible', 'off');
                    set(visible_group, 'Visible', 'on');
                    enable_group = findall(app.PeriodPanel, 'Enable', 'off');
                    set(enable_group, 'Enable', 'on');
                    
%                     app.PeriodButton.Visible = true;
%                     app.PeriodEditField.Visible = true;
                    
                    plot(app.UIAxes, X,movementSpeed, X,torsoSpeed, X,legSpeed);
                    
                    if app.IsPeriodDetected % show period detail
                        plotPeriodDetail(app);
                    end
                    legend(app.UIAxes, 'movement speed', 'torso speed', 'leg speed');
                    
                case {'CombinedButton', 'Combined'}
                    if app.RenewSpectrogramFlag % no available spectrogram
                        generateSpectrogram(app);
                    end
                    if app.RenewSpeedFlag % no available speed
                        generateSpeeds(app);
                    end
                    if app.IsPeriodDetected % show period detail
                        plotPeriodDetail(app);
                    end
%                     app.SpeedDetailGridLayout.Visible = true;
                    % change gridlayout, reduce spectrogram area
%                     app.UserDataTabGridLayout.RowHeight = {'1.5x','1.12x'};
%                     app.GridLayout3.RowHeight = {'2x', '1x'};
                    enable_group = findall(app.SpeedDetailGridLayout, 'Enable', 'off');
                    set(enable_group, 'Enable', 'on');
                    
                    X_spectrum = app.SpectrogramAxesDict('X');
                    Y_spectrum = app.SpectrogramAxesDict('Y');
                    spectrogram = app.SpectrogramAxesDict('Spectrogram');

                    movementSpeed = app.SpeedAxesDict('MovementSpeed');
                    torsoSpeed = app.SpeedAxesDict('TorsoSpeed');
                    legSpeed = app.SpeedAxesDict('LegSpeed');
                    X_speed = app.SpeedAxesDict('X');
                    Y_speed = app.SpeedAxesDict('Y');

                    visible_group = findall(app.PeriodPanel, 'Visible', 'off');
                    set(visible_group, 'Visible', 'on');
                    enable_group = findall(app.PeriodPanel, 'Enable', 'off');
                    set(enable_group, 'Enable', 'on');

                    
                    cla(app.UIAxes, 'reset');
                    hold(app.UIAxes, 'on');
                    
%                     imagesc(app.UIAxes, spectrogram);
                    
                    yyaxis(app.UIAxes, 'left');
                    app.UIAxes.YLabel.String = 'Frequency (Hz)';
                    app.UIAxes.YLim = [0, Y_spectrum(end)];
                    
                    app.UIAxes.XLim = [1, length(X_spectrum)];
%                     h = mesh(app.UIAxes, X_spectrum, Y_spectrum, spectrogram);
                    imagesc(app.UIAxes, spectrogram);
                    
                    yyaxis(app.UIAxes, 'right');
                    app.UIAxes.YLabel.String = 'Speed (m/s)';
                    app.UIAxes.YLim = [0, Y_speed(end)];
                    
                    % https://ww2.mathworks.cn/help/matlab/ref/line.html
                    plot(app.UIAxes,X_speed,movementSpeed,'Color','#D95319','LineStyle','-','LineWidth',1);
                    plot(app.UIAxes,X_speed,torsoSpeed,'Color','#4254F5','LineStyle','--');
                    plot(app.UIAxes,X_speed,legSpeed,'Color','g','LineStyle','--');
                    
                    app.UIAxes.Title.String = 'Spectrogram & Speeds';
                    if app.IsPeriodDetected % show period detail
                        plotPeriodDetail(app);
                    end
                    legend(app.UIAxes, 'movement speed', 'torso speed', 'leg speed');
%                     axis xy;
%                     colormap h.FaceColor;  
%                     disp(get(h));
%                     disp(size(spectrogram));
%                     plot(app.UIAxes, X_speed,movementSpeed, X_speed,torsoSpeed, X_speed,legSpeed);
%                     legend(app.UIAxes, 'movement speed', 'torso speed', 'leg speed');
            end
%             cla(app.UIAxes)
%             hold(app.UIAxes, 'on')
%             mesh(app.UIAxes, X, Y, spectrogram)
        end
        
        function generateSpeeds(app)
            %% extract torso speeds
            spectrumbins = app.SpectrogramAxesDict('Spectrogram'); % size: 101*2678            
            freq_axis = app.SpectrogramAxesDict('Y');
            
            fc_contour = derive_torso_contour_frequency(freq_axis, spectrumbins, 0.03);
            
            % lowpass(fc_contour,150,1000)
            fc = 300;
            fs = 1000;
            
            [b,a] = butter(10, fc/(fs/2));
            fc_filter = filter(b,a,fc_contour);
            fc_contour_freq_smooth = smoothdata(fc_filter,'gaussian',250); % 250大概是500ms的一半，也就是近似步态周期的一半
            movement_speed = freq2speed(fc_contour_freq_smooth, app.WaveLength);
            
            torso_freq = derive_frequency_by_cumulated_percentage(freq_axis, spectrumbins, 0.5); % 提取躯干频率
            % plot(torso_freq);
            torso_filter = filter(b,a,torso_freq);
            torso_freq_smooth = smoothdata(torso_filter,'gaussian',250); % 250大概是500ms的一半，也就是近似步态周期的一半
            torso_speed = freq2speed(torso_freq_smooth, app.WaveLength);
            
            leg_freq = derive_frequency_by_cumulated_percentage(freq_axis, spectrumbins, 0.95); % 提取腿部频率
            leg_filter = filter(b,a,leg_freq);
            leg_freq_smooth = smoothdata(leg_filter,'gaussian',250); % 250大概是500ms的一半，也就是近似步态周期的一半
            leg_speed = freq2speed(leg_freq_smooth, app.WaveLength);
            
            app.SpeedAxesDict('X') = squeeze(app.SpectrogramAxesDict('X'));
            app.SpeedAxesDict('Y') = freq_axis * app.WaveLength / 2; % f=2v/λ
            app.SpeedAxesDict('MovementSpeed') = movement_speed;
            app.SpeedAxesDict('TorsoSpeed') = torso_speed;
            app.SpeedAxesDict('LegSpeed') = leg_speed;
            
            app.RenewSpeedFlag = false;
            
            %     plot(x,movement_speed, x,torso_speed, x,leg_speed);
            %     legend('movement speed', 'torso speed', 'leg speed');
            
        end
        
        function updateSpeedDetail(app)
            % update speed detail
            movementSpeed = app.SpeedAxesDict('MovementSpeed');
            torsoSpeed = app.SpeedAxesDict('TorsoSpeed');
            legSpeed = app.SpeedAxesDict('LegSpeed');
            app.MovementMax.Text = num2str(max(movementSpeed));
            app.MovementMin.Text = num2str(min(movementSpeed));
            app.MovementAverage.Text = num2str(mean(movementSpeed));

            app.TorsoMax.Text = num2str(max(torsoSpeed));
            app.TorsoMin.Text = num2str(min(torsoSpeed));
            app.TorsoAverage.Text = num2str(mean(torsoSpeed));

            app.LegMax.Text = num2str(max(legSpeed));
            app.LegMin.Text = num2str(min(legSpeed));
            app.LegAverage.Text = num2str(mean(legSpeed));
        end
        
        function plotPeriodDetail(app)
            left_index = app.SpeedAxesDict('LeftIndex');
            right_index = app.SpeedAxesDict('RightIndex');
            detect_period = app.SpeedAxesDict('Period');
            locs = app.SpeedAxesDict('Locs');
            movement_speed = app.SpeedAxesDict('MovementSpeed');

            xline(app.UIAxes, [left_index, right_index], 'red', 'LineWidth', 2);
            for i=1:length(locs)
                if locs(i) <= length(app.SpeedAxesDict('X'))
                    plot(app.UIAxes, locs(i), movement_speed(locs(i)), '.', 'MarkerSize', 15, 'Color', 'red');
                end
            end

%             legend(app.UIAxes, 'movement speed', 'torso speed', 'leg speed');
        end
        
        function pathname = uiGetMultiDirs(~, start_path, dialog_title)
            % copy from https://ww2.mathworks.cn/matlabcentral/fileexchange/32555-uigetfile_n_dir-select-multiple-files-and-directories
            % Pick multiple directories and/or files
            import javax.swing.JFileChooser;
            if nargin == 0 || strcmp(start_path, '') % Allow a null argument.
                start_path = pwd;
            end
            jchooser = javaObjectEDT('javax.swing.JFileChooser', start_path);
            jchooser.setFileSelectionMode(JFileChooser.DIRECTORIES_ONLY);
            if nargin > 1
                jchooser.setDialogTitle(dialog_title);
            end
            jchooser.setMultiSelectionEnabled(true);
            status = jchooser.showOpenDialog([]);
            if status == JFileChooser.APPROVE_OPTION
                jFile = jchooser.getSelectedFiles();
                jchooser.setAcceptAllFileFilterUsed(true);
	            pathname{size(jFile, 1)}=[];
                for i=1:size(jFile, 1)
                    pathname{i} = char(jFile(i).getAbsolutePath);
                end
	            
            elseif status == JFileChooser.CANCEL_OPTION
                pathname = [];
            else
                error('Error occured while picking file.');
            end
        end
        
        function validation = checkCSIPathValid(~, dirpath)
            [~, dir] = fileparts(dirpath);
            % use regexp to match valid user dir name
            pat = regexpPattern('user\d+-*');
            if contains(dir, pat, 'IgnoreCase',true)
                validation = true;
            else
                validation = false;
            end
        end
        
        function result_mat = extractFeaturesByDirs(~, dirs, userlabels)
            file_count = 0;
            for k=1:length(dirs)
                file_count = size(dir(append(strjoin(dirs(k)), '\*.dat')), 1) + file_count;
            end

            feature_len_range = 1:415;
            % test = extract_file_features(append(data_dir, data_array(3).name)); % 先提取一次特征得到特征长度，列表前两个是.和..
            feature_len = length(feature_len_range);
            feature_mat = zeros(file_count, feature_len);
            label_array = zeros(file_count, 1); % 标签
            filename_array = repmat("empty", file_count, 1); % 文件名

            % iterate all files and show progress
%             fig = uifigure;
%             d = uiprogressdlg(fig,'Title','正在提取CSI特征...',...
%                 'Message','wait a minute...','Cancelable','on');
            f = waitbar(0,'wait a minute...','Name','正在提取CSI特征...',...
                'CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
            
            setappdata(f,'canceling',0);
            drawnow;
            in_use_index = 1;
            processed = 0;
            for j=1:1:length(dirs)
                dirname = strjoin(dirs(j));
                data_array = dir(append(dirname, '\*.dat'));
                break_flag = false;
                for i = 1:length(data_array) % 列表前两个是.和..，从第三个开始遍历
                    processed = processed + 1;
%                     if d.CancelRequested
%                         break_flag = true;
%                         break;
%                     end
                    if getappdata(f,'canceling')
                        break_flag = true;
                        break;
                    end
                    % 排除空文件
                    if data_array(i).bytes == 0
                        continue;
                    end
                    try
                        feature_mat(in_use_index, :) = extract_file_features(fullfile(dirname, data_array(i).name));
                        label_array(in_use_index) = userlabels(j);
                        filename_array(in_use_index) = fullfile(dirname, data_array(i).name);
                        in_use_index = in_use_index+1;
%                         d.Value = i/file_count;
%                         d.Message = ['已处理 ' num2str(i) ' / ' num2str(file_count)];
                        waitbar(processed/file_count,f,['已处理 ' num2str(processed) ' / ' num2str(file_count)])
                    catch
%                         popupErrorWindow(app, ['处理该CSI文件时遇到错误：' fullfile(dirname, data_array(i).name)]);
                        continue;
                        % d.CancelRequested = true;
                    end
                end
                % 拼接特征和标签
                in_use_index = in_use_index-1;
                if break_flag
                    break;
                end
            end
%             close(d);
            delete(f)
            result_mat = horzcat(feature_mat(1:in_use_index, :), label_array(1:in_use_index, :));
%             disp(result_mat);
        end
        
        function [train_data, test_data] = stratisfiedRandomPick(~, data, ratio)
            train_data=[];
            test_data=[];
            labels = data(:, end);
            rng(0); % 种子保证可重用性
            classes = unique(labels);
            %% 层次抽样 在数据的每一类中，按一定比例抽取数据，构成训练集，剩下的作为测试集
            for i=1:length(classes)
                cate = find(labels==classes(i));
                half = int32(length(cate)*ratio);
                train_tmp = cate(randperm(length(cate),half));  %当前类下，抽取的训练集的所在行
                test_tmp = setdiff(cate,train_tmp);   % 当前类下，剩余的也就是测试集的所在行
                train_data = [train_data;data(train_tmp,1:end)];
                test_data = [test_data;data(test_tmp,1:end)];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'CSI步态检测';
            [dir, ~, ~] = fileparts(mfilename('fullpath'));
            addpath(genpath(dir)); % add functions to environment
            % TODO: check tools dir to use 'generate_csi_from_dat' etc.
%             [csi_data, ~] = generate_csi_from_dat('D:\Documents\14self\wireless-recognition\gait-WiDar\Gait_Dataset\CSI_Gait\20190627\user1\user1-1-1-r1.dat', 3, 30);
            % add uitogglebutton
%             app.testButton.Text = '频谱';
%             app.testButton.Layout.Row = 1;
%             app.testButton.Layout.Column = 1;
            % construct values
            app.SpectrogramAxesDict = containers.Map();
            app.SpeedAxesDict = containers.Map();
            app.AxesButtonStatus = app.SpectrogramAxesButton.Tag; % set default view as spectrogram
            app.SpectrogramAxesButton.Value = true;
            % set renew flag to true, renew generetion needed
            app.RenewSpectrogramFlag = true;
            app.RenewSpeedFlag = true;

            app.TrainResult = containers.Map();
            app.TestResult = containers.Map();
            app.IsModelSelected = false;
%             % 填充表格
%             result_mat = load('dataset-all.mat').result_mat;
%             app.UITable.Data = result_mat;
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            [file, path] = uigetfile('*.dat', '选择CSI *.dat文件', 'D:\Documents\14self\wireless-recognition\gait-WiDar\Gait_Dataset\dataset');  % open a dat file
%             [file, path] = uigetfile('*.dat', '选择CSI *.dat文件', 'D:\Documents\14self\wireless-recognition\gait-WiDar\Gait_Dataset\CSI_Gait\plot_data');  % open a dat file
            drawnow;
            figure(app.UIFigure) % focus figure in the front, according to https://ww2.mathworks.cn/matlabcentral/answers/296305-appdesigner-window-ends-up-in-background-after-uigetfile
            if ~isequal(file,0) % user canceled file selection
                filename = fullfile(path, file);
                % check if filename is valid
                if ~isempty(regexp(filename, '[/\*:?"<>|]', 'once'))
                    app.FilenameEditField.Enable = 'on';
                    app.LoadFileButton.Enable = 'on';
                    app.FilenameEditField.Value = filename;
                    app.Filename = filename;
                else
                    uiwait(msgbox('文件路径不正确','Error', 'modal'));
                end
            end
            % require to re-generate speed and spectrogram
            app.RenewSpectrogramFlag = true;
            app.RenewSpeedFlag = true;
            app.IsPeriodDetected = false;

            % initialize all fileds with values
            visible_group = findall(app.PeriodPanel, 'Visible', 'on');
            set(visible_group, 'Visible', 'off');
            visible_group = findall(app.PacketDetailPanel, 'Visible', 'on');
            set(visible_group, 'Visible', 'off');
            initialize_group = findall(app.SpeedDetailGridLayout, 'Tag', 'num');
            set(initialize_group, 'Text', '0');
            enable_group = findall(app.ViewButtonGridLayout, 'Enable', 'on');
            set(enable_group, 'Enable', 'off');
            enable_group = findall(app.SpeedDetailGridLayout, 'Enable', 'on');
            set(enable_group, 'Enable', 'off');
            cla(app.UIAxes, 'reset');
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            filename = app.Filename;
%             disp(class(filename));
            try
                csi_trace = read_bf_file(filename);
                if size(csi_trace, 1) == 0 % empty csi dat file
                    ME = MException('MyComponent:inputError', 'CSI文件可能为空，请检查dat文件');
                    throw(ME)
                end
                scaled_first_csi = get_scaled_csi(csi_trace{1});
                app.Tx = size(scaled_first_csi, 1);
                app.Rx = size(scaled_first_csi, 2);
                app.Subcarriers = size(scaled_first_csi, 3);
                app.PeriodEditField.Value = 0;
                app.PeriodDiffEditField.Value = 0;
                % visualize and enable packet detail panel
                visible_group = findall(app.PacketDetailPanel, 'Visible', 'off');
                enable_group = findall(app.PacketDetailPanel, 'Enable', 'off');
                set(visible_group, 'Visible', 'on');
                set(enable_group, 'Enable', 'on');
                % enable view button grid layout
                enable_group = findall(app.ViewButtonGridLayout, 'Enable', 'off');
                set(enable_group, 'Enable', 'on');
                generateSpectrogram(app);
                
                % plot Tx and Rx num
                app.TxAntennaEditField.Value = app.Tx;
                app.RxAntennaEditField.Value = app.Rx;
                app.SampleRateEditField.Value = app.SampleRate;
                
                % visualize period detail layout
                visible_group = findall(app.PeriodPanel, 'Visible', 'off');
                set(visible_group, 'Visible', 'on');
                enable_group = findall(app.PeriodPanel, 'Enable', 'off');
                set(enable_group, 'Enable', 'on');
%                 enable_group = findall(app.PeriodPanel, 'Enable', 'on');
%                 set(enable_group, 'Enable', 'off');
%                 app.PeriodButton.Enable = 'on';
                visible_group = findall(app.PredictGridLayout, 'Visible', 'off');
                set(visible_group, 'Visible', 'on');
%                 enable_group = findall(app.PredictGridLayout, 'Enable', 'off');
%                 set(enable_group, 'Enable', 'on');
%                 app.PredictLabel.Enable = 'off';
                app.SelectUseModelButton.Enable = 'on';

                % get current view
                view_button = findobj(app.ViewButtonGridLayout, 'Value', true);
                
                
                updateUIAxes(app, view_button.Tag);
            catch ME
                popupErrorWindow(app, ME.message);
            end
        end

        % Value changed function: CombineAxesButton, 
        % ...and 2 other components
        function AxesButtonValueChanged(app, event)
            % for containing all UI components in App Designer, this app
            % doesn't create 'uitogglebutton' with codes but used 'uibutton' 
            % that app designer supplies, and implements basic functions of
            % 'uitogglebutton' manually, i.e. mutually exclusive selection
            
            % currently selected button tag is not the same as the previous
            if ~strcmp(app.AxesButtonStatus, event.Source.Tag)
                previosButton = findall(app.ViewButtonGridLayout, 'Tag', app.AxesButtonStatus);
                set(previosButton, 'Value', false);
                app.AxesButtonStatus = event.Source.Tag;
                updateUIAxes(app, event.Source.Tag);
            else % currently selected button tag is the same as the previous
                set(event.Source, 'Value', true);
            end

        
%             if app.SpectrogramAxesButton.Value && ~strcmp(app.AxesButtonStatus, app.SpectrogramAxesButton.Tag)
%                 previosButton = findall(app.ViewButtonGridLayout, 'Tag', app.AxesButtonStatus);
%                 set(previosButton, 'Value', false);
%                 app.AxesButtonStatus = app.SpectrogramAxesButton.Value; % update view button status
%             elseif app.SpeedAxesButton.Value && ~strcmp(app.AxesButtonStatus, app.SpeedAxesButton.Tag)
%                 previosButton = findall(app.ViewButtonGridLayout, 'Tag', app.AxesButtonStatus);
%                 set(previosButton, 'Value', false);
%                 app.AxesButtonStatus = app.SpeedAxesButton.Value; % update view button status
% %                 
% %                 updateUIAxes(app, )
%             
%             end
            
%             value = app.SpectrogramAxesButton.Value;
            
        end

        % Button pushed function: PeriodButton
        function PeriodButtonPushed(app, event)
%             %% autocorrelation
%             % extract period where speed is no less than 80% maxmium speed
%             movement_speed = app.SpeedAxesDict('MovementSpeed');
%             max_speed_80 = max(movement_speed) * 0.8;
%             max_speed_60 = max(movement_speed) * 0.7;
%             left_index = 0;
%             right_index = 0;
%             for i=1:length(movement_speed)
%                if movement_speed(1, i) > max_speed_80
%                    left_index = i;
%                    break;
%                end
%             end
%             for i=length(movement_speed):-1:1
%                 if movement_speed(1, i) > max_speed_60
%                    right_index = i;
%                    break;
%                 end
%             end
%             xline(app.UIAxes, [left_index, right_index]);
%             detect_period = movement_speed(left_index:right_index);
%             app.PeriodEditField.Value = autocorrelation(detect_period);
            %% change view
            view_button = findobj(app.ViewButtonGridLayout, 'Value', true);
            if strcmp(view_button.Tag, 'SpectrogramButton')
                myEvent.Source.Tag = app.SpeedAxesButton.Tag;
                AxesButtonValueChanged(app, myEvent);
                app.SpeedAxesButton.Value = true;
            end
            %% autocorrelation
            % extract period where speed is no less than 80% maxmium speed
            movement_speed = app.SpeedAxesDict('MovementSpeed');
            max_speed_80 = max(movement_speed) * 0.8;
            max_speed_75 = max(movement_speed) * 0.75;
            left_index = 0;
            right_index = 0;
            for i=1:length(movement_speed)
               if movement_speed(1, i) > max_speed_80
                   left_index = i;
                   break;
               end
            end
            for i=length(movement_speed):-1:1
                if movement_speed(1, i) > max_speed_75
                   right_index = i;
                   break;
                end
            end
            
            detect_period = movement_speed(left_index:right_index);
            remove_average = detect_period - mean(detect_period);
            [period, locs, diffs] = autocorrelation(remove_average);

            locs = locs + left_index;
%             for i=1:length(locs)
%                 if locs(i) <= length(app.SpeedAxesDict('X'))
%                     plot(app.UIAxes, locs(i), movement_speed(locs(i)), '.', 'MarkerSize', 15, 'Color', 'red');
%                 end
%             end
%             legend(app.UIAxes, 'movement speed', 'torso speed', 'leg speed');
            if length(diffs) == 1
                difference = 1;
            else
                difference = max(diffs)-min(diffs);
            end

            app.PeriodEditField.Value = period;
            app.PeriodDiffEditField.Value = difference;

            app.SpeedAxesDict('LeftIndex') = left_index;
            app.SpeedAxesDict('RightIndex') = right_index;
            app.SpeedAxesDict('Period') = detect_period;
            app.SpeedAxesDict('Locs') = locs;
            app.IsPeriodDetected = true;
            plotPeriodDetail(app);
            legend(app.UIAxes, 'movement speed', 'torso speed', 'leg speed');
%             app.PeriodEditField.Value = autocorrelation(detect_period);
        end

        % Button pushed function: SelectDirButton
        function SelectDirButtonPushed(app, event)
            directory_cells = uiGetMultiDirs(app, 'D:\Documents\14self\wireless-recognition\gait-WiDar\Gait_Dataset\dataset', '选择多个包含CSI文件的文件夹，按住ctrl多选');  % open a directory containing csi file subdirs
%             [file, path] = uigetfile('*.dat', '选择CSI *.dat文件', 'D:\Documents\14self\wireless-recognition\gait-WiDar\Gait_Dataset\CSI_Gait\plot_data');  % open a dat file
            drawnow;
            if isempty(directory_cells)
                return
            end
            directory = strjoin(directory_cells, ';');
            app.DirNameEditField.Value = directory;
            figure(app.UIFigure) % focus figure in the front, according to https://ww2.mathworks.cn/matlabcentral/answers/296305-appdesigner-window-ends-up-in-background-after-uigetfile
            
            % check if selected dirs legal - legal format: user*(-*), and the (-*) suffix could be notes
            invalid_paths = {};
            for i=1:length(directory_cells)
                if checkCSIPathValid(app, directory_cells(i)) == false
                    invalid_paths = [invalid_paths directory_cells(i)];
                end
            end
            if isempty(invalid_paths) == false
                popupErrorWindow(app, ...
                    ["请检查以下文件夹是否符合命名规范：userX(-xxx)，(-xxx)为可选项" ...
                    strjoin(invalid_paths, '\n')]);
                return
            end

            % TODOOOOOOOOOOOOOOOOOO: check if all csi files have the same size
            % extract user IDs
            userids = repmat("", length(directory_cells), 1); % id list
            for i = 1:length(directory_cells)
                [~, dir] = fileparts(directory_cells(i));
                % validation already checked, so we can extract userid by index directly
                dirsplit = split(dir, '-');
                username = strjoin(dirsplit(1));
                userids(i) = username(5:end);
            end

            % store dirs if all dirs are valid
            app.SelectedDirs = directory_cells;
            app.SelectedUserlabels = userids;
            app.ImportasUserLabel.Text = ['Imported as User', strjoin(userids, '、')];

            % update layout
            app.LoadDirButton.Enable = 'on';
            visible_group = findall(app.ViewButtonGridLayout, 'Visible', 'off');
            set(visible_group, 'Visible', 'on');
            enable_group = findall(app.ViewButtonGridLayout, 'Enable', 'off');
            set(enable_group, 'Enable', 'on');
        end

        % Button pushed function: LoadDirButton
        function LoadDirButtonPushed(app, event)
            app.ResultMat = extractFeaturesByDirs(app, app.SelectedDirs, app.SelectedUserlabels);
            for i=1:length(app.SelectedUserlabels)
                label = str2double(app.SelectedUserlabels(i));
                one_cate = app.ResultMat(app.ResultMat(:, end)==label, 1:end, :);
                % plot data
                newusertab = uitab(app.TabGroup2, 'Title', append('User', app.SelectedUserlabels(i)));
                tmp = copyobj(app.UserDataTabGridLayout, newusertab);
                uitablehandles = copyobj(app.UITable, newusertab);
                uitablehandles.Data = [one_cate(:, 1:2) one_cate(:, 4:end-1)];
            end
            delete(app.UserDataTab);
            visible_group = findall(app.TrainDetailPanel, 'Visible', 'off');
            set(visible_group, 'Visible', 'on');
            visible_group = findall(app.ImportPacketDetailPanel, 'Visible', 'off');
            set(visible_group, 'Visible', 'on');
            enable_group = findall(app.TrainDetailPanel, 'Enable', 'off');
            set(enable_group, 'Enable', 'on');
            enable_group = findall(app.ImportPacketDetailPanel, 'Enable', 'off');
            set(enable_group, 'Enable', 'on');
            app.SaveModelButton.Enable = 'off';

            % TODOOOOOOOOOOOO:
            app.ImportRxAntennaEditField.Value = 3;
            app.ImportTxAntennaEditField.Value = 1;
            app.ImportSampleRateEditField.Value = 1000;
        end

        % Button pushed function: TrainStartButton
        function TrainStartButtonPushed(app, event)
            % TODOOOOOOOOOOOO: check if inputs are legal
            kFold = app.KFoldEditField.Value;
            ratio = 1 - app.LeaveoutEditFiled.Value;
            [train_data, test_data] = stratisfiedRandomPick(app, app.ResultMat, ratio);
            [trainedClassifier, validationAccuracy, validationPredictions, validationScores, classes] ...
                = trainClassifier(train_data, kFold);
            app.TrainedClassifier = trainedClassifier;
            app.Classes = classes;
            % confusionchart(trainingData(:, end), validationPredictions);
%             app.TrainROCObjects = rocmetrics(train_data(:, end),validationScores,app.classes);
            app.TrainData = train_data;
            app.TestData = test_data;
            app.TrainResult('ROCObjects') = rocmetrics(train_data(:, end),validationScores,app.Classes);
            app.TrainResult('Acc') = validationAccuracy;
            app.TrainResult('Preds') = validationPredictions;
            
            enable_group = findall(app.TrainTestIndicatorGridLayout, 'Enable', 'off');
            set(enable_group, 'Enable', 'on');
            app.TrainAccNum.Text = append(num2str(100*validationAccuracy), '%');

            % apply test on test data
%             prediction = trainedClassifier.predictFcn(test_data);
            [~, pred_score] = predict(trainedClassifier.ClassificationSVM, [test_data(:, 1:2) test_data(:, 4:end-1)]);
            pred_label = trainedClassifier.predictFcn([test_data(:, 1:2) test_data(:, 4:end-1)]);
            pred_acc = sum(pred_label == test_data(:, end))/numel(test_data(:, end));
            app.TestAccNum.Text = append(num2str(100*pred_acc), '%');
            app.TestResult('ROCObjects') = rocmetrics(test_data(:, end), pred_score, app.Classes);
            app.TestResult('Acc') = pred_acc;
            app.TestResult('Preds') = pred_label;

            % enable save model button
            app.SaveModelButton.Enable = 'on';
%             app
%             accuracy = sum(YPred == YTest)/numel(YTest)
%             trainClassifier(, kFold);
        end

        % Button pushed function: TrainConfusionPushButton
        function TrainConfusionPushButtonPushed(app, event)
            figure('Name','训练数据混淆矩阵','NumberTitle','off');
            cm = confusionchart(app.TrainData(:, end), app.TrainResult('Preds'));
            cm.RowSummary = 'row-normalized';
            cm.ColumnSummary = 'column-normalized';
        end

        % Button pushed function: TrainROCPushButton
        function TrainROCPushButtonPushed(app, event)
            figure('Name','训练数据ROC曲线','NumberTitle','off');
            plot(app.TrainResult('ROCObjects'));
        end

        % Button pushed function: TestConfusionPushButton
        function TestConfusionPushButtonPushed(app, event)
            figure('Name','测试数据混淆矩阵','NumberTitle','off');
            cm = confusionchart(app.TestData(:, end), app.TestResult('Preds'));
            cm.RowSummary = 'row-normalized';
            cm.ColumnSummary = 'column-normalized';
        end

        % Button pushed function: TestROCPushButton
        function TestROCPushButtonPushed(app, event)
            figure('Name','测试数据ROC曲线','NumberTitle','off');
            plot(app.TestResult('ROCObjects'));
        end

        % Button pushed function: SaveModelButton
        function SaveModelButtonPushed(app, event)
            filter = {'*.mat';'*.*'};
            [file, path] = uiputfile(filter, '保存SVM模型文件到...', 'D:\Documents\14self\wireless-recognition\gait-WiDar\Gait_Dataset\dataset\trainedSVM.mat');
            if path == 0
                return
            end
            filename = fullfile(path,file);
            model = app.TrainedClassifier;
            save(filename, "model");
            uiwait(msgbox("已成功保存模型", "提示"));
            drawnow;
            figure(app.UIFigure) % focus figure in the front, according to https://ww2.mathworks.cn/matlabcentral/answers/296305-appdesigner-window-ends-up-in-background-after-uigetfile
        end

        % Button pushed function: SelectUseModelButton
        function SelectUseModelButtonPushed(app, event)
            if app.IsModelSelected == false
                [file, path] = uigetfile('*.mat', '选择检测模型 *.mat文件', 'D:\Documents\14self\wireless-recognition\gait-WiDar\Gait_Dataset\dataset');  % open a dat file
    %             [file, path] = uigetfile('*.dat', '选择CSI *.dat文件', 'D:\Documents\14self\wireless-recognition\gait-WiDar\Gait_Dataset\CSI_Gait\plot_data');  % open a dat file
                drawnow;
                figure(app.UIFigure) % focus figure in the front, according to https://ww2.mathworks.cn/matlabcentral/answers/296305-appdesigner-window-ends-up-in-background-after-uigetfile
                if ~isequal(file,0) % user canceled file selection
                    filename = fullfile(path, file);
                    % check if filename is valid
                    if ~isempty(regexp(filename, '[/\*:?"<>|]', 'once'))
                        app.SelectedModelEditField.Enable = 'on';
                        app.SelectedModelEditField.Value = filename;
                        app.SelectUseModelButton.FontColor = 'green';
                        app.SelectUseModelButton.FontWeight = 'bold';
                        app.ModelFilename = filename;
                        app.IsModelSelected = true;
                        app.SelectUseModelButton.Text = '开始检测！';
                    else
                        uiwait(msgbox('文件路径不正确','Error', 'modal'));
                    end
                end
            else
                app.LoadedModel = load(app.ModelFilename).model;
                features = extract_file_features(app.Filename);
                pred = app.LoadedModel.predictFcn([features(:, 1:2) features(:, 4:end)]);
                app.PredictLabel.Enable = 'on';
                app.PredictLabel.Text = append('User', num2str(pred));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.LoadDataTab = uitab(app.TabGroup);
            app.LoadDataTab.Title = '打开文件';
            app.LoadDataGridLayout = uigridlayout(app.LoadDataTab);
            app.TabLoadDataGridLayout = uigridlayout(app.LoadDataGridLayout);
            app.FileLoadPanel = uipanel(app.TabLoadDataGridLayout);
            app.FileLoadPanel.Title = '选择文件';
            app.FileLoadGridLayout = uigridlayout(app.FileLoadPanel);
            app.LoadFileButton = uibutton(app.FileLoadGridLayout, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Enable = 'off';
            app.LoadFileButton.Text = '加载文件';
            app.FileChosenGridLayout = uigridlayout(app.FileLoadGridLayout);
            app.SelectFileButton = uibutton(app.FileChosenGridLayout, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = {'...'; ''};
            app.FilenameEditField = uieditfield(app.FileChosenGridLayout, 'text');
            app.FilenameEditField.Editable = 'off';
            app.FilenameEditField.Enable = 'off';
            app.PacketDetailPanel = uipanel(app.TabLoadDataGridLayout);
            app.PacketDetailPanel.Enable = 'off';
            app.PacketDetailPanel.Title = '包数据详情';
            app.PacketDetailPanel.Visible = 'off';
            app.PacketDetailGridLayout = uigridlayout(app.PacketDetailPanel);
            app.RxAntennaEditFieldLabel = uilabel(app.PacketDetailGridLayout);
            app.RxAntennaEditFieldLabel.Visible = 'off';
            app.RxAntennaEditFieldLabel.Text = 'Rx Antenna';
            app.TxAntennaEditFieldLabel = uilabel(app.PacketDetailGridLayout);
            app.TxAntennaEditFieldLabel.Visible = 'off';
            app.TxAntennaEditFieldLabel.Text = 'Tx Antenna';
            app.SamplerateEditFieldLabel = uilabel(app.PacketDetailGridLayout);
            app.SamplerateEditFieldLabel.Visible = 'off';
            app.SamplerateEditFieldLabel.Text = 'Sample rate';
            app.RxAntennaEditField = uieditfield(app.PacketDetailGridLayout, 'numeric');
            app.RxAntennaEditField.Limits = [0 Inf];
            app.RxAntennaEditField.Editable = 'off';
            app.RxAntennaEditField.Enable = 'off';
            app.RxAntennaEditField.Visible = 'off';
            app.TxAntennaEditField = uieditfield(app.PacketDetailGridLayout, 'numeric');
            app.TxAntennaEditField.Limits = [0 Inf];
            app.TxAntennaEditField.Editable = 'off';
            app.TxAntennaEditField.Enable = 'off';
            app.TxAntennaEditField.Visible = 'off';
            app.SampleRateEditField = uieditfield(app.PacketDetailGridLayout, 'numeric');
            app.SampleRateEditField.Limits = [0 Inf];
            app.SampleRateEditField.Editable = 'off';
            app.SampleRateEditField.Enable = 'off';
            app.SampleRateEditField.Visible = 'off';
            app.PeriodPanel = uipanel(app.TabLoadDataGridLayout);
            app.PeriodPanel.Enable = 'off';
            app.PeriodPanel.Title = '周期数据';
            app.PeriodPanel.Visible = 'off';
            app.PeriodGridLayout = uigridlayout(app.PeriodPanel);
            app.PeriodButton = uibutton(app.PeriodGridLayout, 'push');
            app.PeriodButton.ButtonPushedFcn = createCallbackFcn(app, @PeriodButtonPushed, true);
            app.PeriodButton.Visible = 'off';
            app.PeriodButton.Text = '检测周期';
            app.PeriodEditField = uieditfield(app.PeriodGridLayout, 'numeric');
            app.PeriodEditField.Editable = 'off';
            app.PeriodEditField.Visible = 'off';
            app.PeriodLabel = uilabel(app.PeriodGridLayout);
            app.PeriodLabel.Enable = 'off';
            app.PeriodLabel.Visible = 'off';
            app.PeriodLabel.Text = '估算周期（ms）';
            app.PeriodDiffLabel = uilabel(app.PeriodGridLayout);
            app.PeriodDiffLabel.Enable = 'off';
            app.PeriodDiffLabel.Visible = 'off';
            app.PeriodDiffLabel.Text = '周期波动（ms）';
            app.PeriodDiffEditField = uieditfield(app.PeriodGridLayout, 'numeric');
            app.PeriodDiffEditField.Editable = 'off';
            app.PeriodDiffEditField.Visible = 'off';
            app.PredictGridLayout = uigridlayout(app.TabLoadDataGridLayout);
            app.PredictLabel = uilabel(app.PredictGridLayout);
            app.PredictLabel.Enable = 'off';
            app.PredictLabel.Visible = 'off';
            app.PredictLabel.Text = 'User?';
            app.SelectUseModelButton = uibutton(app.PredictGridLayout, 'push');
            app.SelectUseModelButton.ButtonPushedFcn = createCallbackFcn(app, @SelectUseModelButtonPushed, true);
            app.SelectUseModelButton.Enable = 'off';
            app.SelectUseModelButton.Visible = 'off';
            app.SelectUseModelButton.Text = '选取模型';
            app.SelectedModelEditField = uieditfield(app.PredictGridLayout, 'text');
            app.SelectedModelEditField.Editable = 'off';
            app.SelectedModelEditField.Enable = 'off';
            app.SelectedModelEditField.Visible = 'off';
            app.LoadDataPlotGridLayout = uigridlayout(app.LoadDataGridLayout);
            app.UIAxes = uiaxes(app.LoadDataPlotGridLayout);
            title(app.UIAxes, 'Spectrogram')
            xlabel(app.UIAxes, 'Time (ms)')
            ylabel(app.UIAxes, 'Frequency (Hz)')
            zlabel(app.UIAxes, 'Z')
            app.PlotDataGridLayout = uigridlayout(app.LoadDataPlotGridLayout);
            app.SpeedDetailGridLayout = uigridlayout(app.PlotDataGridLayout);
            app.TorsoMaxLabel = uilabel(app.SpeedDetailGridLayout);
            app.TorsoMaxLabel.Enable = 'off';
            app.TorsoMaxLabel.Text = 'max';
            app.TorsoAverageLabel = uilabel(app.SpeedDetailGridLayout);
            app.TorsoAverageLabel.Enable = 'off';
            app.TorsoAverageLabel.Text = 'average';
            app.LegMinLabel = uilabel(app.SpeedDetailGridLayout);
            app.LegMinLabel.Enable = 'off';
            app.LegMinLabel.Text = 'min';
            app.TorsoSpeedLabel = uilabel(app.SpeedDetailGridLayout);
            app.TorsoSpeedLabel.Enable = 'off';
            app.TorsoSpeedLabel.Text = '躯干速度';
            app.LegAverageLabel = uilabel(app.SpeedDetailGridLayout);
            app.LegAverageLabel.Enable = 'off';
            app.LegAverageLabel.Text = 'average';
            app.TorsoMinLabel = uilabel(app.SpeedDetailGridLayout);
            app.TorsoMinLabel.Enable = 'off';
            app.TorsoMinLabel.Text = 'min';
            app.MovementMaxLabel = uilabel(app.SpeedDetailGridLayout);
            app.MovementMaxLabel.Enable = 'off';
            app.MovementMaxLabel.Text = 'max';
            app.MovementMinLabel = uilabel(app.SpeedDetailGridLayout);
            app.MovementMinLabel.Enable = 'off';
            app.MovementMinLabel.Text = 'min';
            app.MovementAverageLabel = uilabel(app.SpeedDetailGridLayout);
            app.MovementAverageLabel.Enable = 'off';
            app.MovementAverageLabel.Text = 'average';
            app.LegMaxLabel = uilabel(app.SpeedDetailGridLayout);
            app.LegMaxLabel.Enable = 'off';
            app.LegMaxLabel.Text = 'max';
            app.MovementSpeedLabel = uilabel(app.SpeedDetailGridLayout);
            app.MovementSpeedLabel.Enable = 'off';
            app.MovementSpeedLabel.Text = '移动速度';
            app.LegSpeedLabel = uilabel(app.SpeedDetailGridLayout);
            app.LegSpeedLabel.Enable = 'off';
            app.LegSpeedLabel.Text = '腿部速度';
            app.MovementMax = uilabel(app.SpeedDetailGridLayout);
            app.MovementMax.Tag = 'num';
            app.MovementMax.Enable = 'off';
            app.MovementMax.Text = '0';
            app.TorsoMax = uilabel(app.SpeedDetailGridLayout);
            app.TorsoMax.Tag = 'num';
            app.TorsoMax.Enable = 'off';
            app.TorsoMax.Text = '0';
            app.LegMax = uilabel(app.SpeedDetailGridLayout);
            app.LegMax.Tag = 'num';
            app.LegMax.Enable = 'off';
            app.LegMax.Text = '0';
            app.MovementMin = uilabel(app.SpeedDetailGridLayout);
            app.MovementMin.Tag = 'num';
            app.MovementMin.Enable = 'off';
            app.MovementMin.Text = '0';
            app.TorsoMin = uilabel(app.SpeedDetailGridLayout);
            app.TorsoMin.Tag = 'num';
            app.TorsoMin.Enable = 'off';
            app.TorsoMin.Text = '0';
            app.LegMin = uilabel(app.SpeedDetailGridLayout);
            app.LegMin.Tag = 'num';
            app.LegMin.Enable = 'off';
            app.LegMin.Text = '0';
            app.MovementAverage = uilabel(app.SpeedDetailGridLayout);
            app.MovementAverage.Tag = 'num';
            app.MovementAverage.Enable = 'off';
            app.MovementAverage.Text = '0';
            app.TorsoAverage = uilabel(app.SpeedDetailGridLayout);
            app.TorsoAverage.Tag = 'num';
            app.TorsoAverage.Enable = 'off';
            app.TorsoAverage.Text = '0';
            app.LegAverage = uilabel(app.SpeedDetailGridLayout);
            app.LegAverage.Tag = 'num';
            app.LegAverage.Enable = 'off';
            app.LegAverage.Text = '0';
            app.ViewButtonGridLayout = uigridlayout(app.PlotDataGridLayout);
            app.SpectrogramAxesButton = uibutton(app.ViewButtonGridLayout, 'state');
            app.SpectrogramAxesButton.ValueChangedFcn = createCallbackFcn(app, @AxesButtonValueChanged, true);
            app.SpectrogramAxesButton.Tag = 'SpectrogramButton';
            app.SpectrogramAxesButton.Enable = 'off';
            app.SpectrogramAxesButton.Text = '频谱';
            app.SpeedAxesButton = uibutton(app.ViewButtonGridLayout, 'state');
            app.SpeedAxesButton.ValueChangedFcn = createCallbackFcn(app, @AxesButtonValueChanged, true);
            app.SpeedAxesButton.Tag = 'SpeedButton';
            app.SpeedAxesButton.Enable = 'off';
            app.SpeedAxesButton.Text = '速度';
            app.CombineAxesButton = uibutton(app.ViewButtonGridLayout, 'state');
            app.CombineAxesButton.ValueChangedFcn = createCallbackFcn(app, @AxesButtonValueChanged, true);
            app.CombineAxesButton.Tag = 'CombinedButton';
            app.CombineAxesButton.Enable = 'off';
            app.CombineAxesButton.Text = '合并图';
            app.TrainModelTab = uitab(app.TabGroup);
            app.TrainModelTab.Title = '录入新数据';
            app.ImportDataGridLayout = uigridlayout(app.TrainModelTab);
            app.TabLoadDataGridLayout_2 = uigridlayout(app.ImportDataGridLayout);
            app.DirLoadPanel = uipanel(app.TabLoadDataGridLayout_2);
            app.DirLoadPanel.Title = '选择文件夹';
            app.DirLoadGridLayout = uigridlayout(app.DirLoadPanel);
            app.LoadDirButton = uibutton(app.DirLoadGridLayout, 'push');
            app.LoadDirButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDirButtonPushed, true);
            app.LoadDirButton.Enable = 'off';
            app.LoadDirButton.Text = '加载CSI文件夹';
            app.DirChosenGridLayout = uigridlayout(app.DirLoadGridLayout);
            app.SelectDirButton = uibutton(app.DirChosenGridLayout, 'push');
            app.SelectDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirButtonPushed, true);
            app.SelectDirButton.Text = {'...'; ''};
            app.DirNameEditField = uieditfield(app.DirChosenGridLayout, 'text');
            app.DirNameEditField.Editable = 'off';
            app.DirNameEditField.Enable = 'off';
            app.ImportPacketDetailPanel = uipanel(app.TabLoadDataGridLayout_2);
            app.ImportPacketDetailPanel.Enable = 'off';
            app.ImportPacketDetailPanel.Title = '包数据详情';
            app.ImportPacketDetailPanel.Visible = 'off';
            app.ImportPacketDetailGridLayout = uigridlayout(app.ImportPacketDetailPanel);
            app.ImportRxAntennaEditFieldLabel = uilabel(app.ImportPacketDetailGridLayout);
            app.ImportRxAntennaEditFieldLabel.Visible = 'off';
            app.ImportRxAntennaEditFieldLabel.Text = 'Rx Antenna';
            app.ImportTxAntennaEditFieldLabel = uilabel(app.ImportPacketDetailGridLayout);
            app.ImportTxAntennaEditFieldLabel.Visible = 'off';
            app.ImportTxAntennaEditFieldLabel.Text = 'Tx Antenna';
            app.ImportSamplerateEditFieldLabel = uilabel(app.ImportPacketDetailGridLayout);
            app.ImportSamplerateEditFieldLabel.Visible = 'off';
            app.ImportSamplerateEditFieldLabel.Text = 'Sample rate';
            app.ImportRxAntennaEditField = uieditfield(app.ImportPacketDetailGridLayout, 'numeric');
            app.ImportRxAntennaEditField.Limits = [0 Inf];
            app.ImportRxAntennaEditField.Editable = 'off';
            app.ImportRxAntennaEditField.Enable = 'off';
            app.ImportRxAntennaEditField.Visible = 'off';
            app.ImportTxAntennaEditField = uieditfield(app.ImportPacketDetailGridLayout, 'numeric');
            app.ImportTxAntennaEditField.Limits = [0 Inf];
            app.ImportTxAntennaEditField.Editable = 'off';
            app.ImportTxAntennaEditField.Enable = 'off';
            app.ImportTxAntennaEditField.Visible = 'off';
            app.ImportSampleRateEditField = uieditfield(app.ImportPacketDetailGridLayout, 'numeric');
            app.ImportSampleRateEditField.Limits = [0 Inf];
            app.ImportSampleRateEditField.Editable = 'off';
            app.ImportSampleRateEditField.Enable = 'off';
            app.ImportSampleRateEditField.Visible = 'off';
            app.TrainDetailPanel = uipanel(app.TabLoadDataGridLayout_2);
            app.TrainDetailPanel.Enable = 'off';
            app.TrainDetailPanel.Title = '训练过程参数设置';
            app.TrainDetailPanel.Visible = 'off';
            app.TrainDetailPeriodGridLayout = uigridlayout(app.TrainDetailPanel);
            app.TrainStartButton = uibutton(app.TrainDetailPeriodGridLayout, 'push');
            app.TrainStartButton.ButtonPushedFcn = createCallbackFcn(app, @TrainStartButtonPushed, true);
            app.TrainStartButton.Visible = 'off';
            app.TrainStartButton.Text = '开始训练';
            app.KFoldEditField = uieditfield(app.TrainDetailPeriodGridLayout, 'numeric');
            app.KFoldEditField.Visible = 'off';
            app.KFoldEditField.Value = 5;
            app.KFoldLabel = uilabel(app.TrainDetailPeriodGridLayout);
            app.KFoldLabel.Enable = 'off';
            app.KFoldLabel.Visible = 'off';
            app.KFoldLabel.Text = '交叉验证折数';
            app.LeaveoutLabel = uilabel(app.TrainDetailPeriodGridLayout);
            app.LeaveoutLabel.Enable = 'off';
            app.LeaveoutLabel.Visible = 'off';
            app.LeaveoutLabel.Text = '留出验证比例';
            app.LeaveoutEditFiled = uieditfield(app.TrainDetailPeriodGridLayout, 'numeric');
            app.LeaveoutEditFiled.Visible = 'off';
            app.LeaveoutEditFiled.Value = 0.2;
            app.SaveModelButton = uibutton(app.TrainDetailPeriodGridLayout, 'push');
            app.SaveModelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModelButtonPushed, true);
            app.SaveModelButton.Visible = 'off';
            app.SaveModelButton.Text = '保存模型';
            app.ImportasUserLabel = uilabel(app.TabLoadDataGridLayout_2);
            app.ImportasUserLabel.Text = 'Import as User...';
            app.ImportDataPlotGridLayout = uigridlayout(app.ImportDataGridLayout);
            app.TrainTestIndicatorGridLayout = uigridlayout(app.ImportDataPlotGridLayout);
            app.TestAccLabel = uilabel(app.TrainTestIndicatorGridLayout);
            app.TestAccLabel.Enable = 'off';
            app.TestAccLabel.Text = '准确率';
            app.TestROCLabel = uilabel(app.TrainTestIndicatorGridLayout);
            app.TestROCLabel.Enable = 'off';
            app.TestROCLabel.Text = 'ROC';
            app.TestIndicatorLabel = uilabel(app.TrainTestIndicatorGridLayout);
            app.TestIndicatorLabel.Enable = 'off';
            app.TestIndicatorLabel.Text = '测试集指标';
            app.TestConfusionLabel = uilabel(app.TrainTestIndicatorGridLayout);
            app.TestConfusionLabel.Enable = 'off';
            app.TestConfusionLabel.Text = '混淆矩阵';
            app.TrainAccLabel = uilabel(app.TrainTestIndicatorGridLayout);
            app.TrainAccLabel.Enable = 'off';
            app.TrainAccLabel.Text = '准确率';
            app.TrainConfusionLabel = uilabel(app.TrainTestIndicatorGridLayout);
            app.TrainConfusionLabel.Enable = 'off';
            app.TrainConfusionLabel.Text = '混淆矩阵';
            app.TrainROCLabel = uilabel(app.TrainTestIndicatorGridLayout);
            app.TrainROCLabel.Enable = 'off';
            app.TrainROCLabel.Text = 'ROC';
            app.TrainIndicatorLabel = uilabel(app.TrainTestIndicatorGridLayout);
            app.TrainIndicatorLabel.Enable = 'off';
            app.TrainIndicatorLabel.Text = '验证集指标';
            app.TrainAccNum = uilabel(app.TrainTestIndicatorGridLayout);
            app.TrainAccNum.Enable = 'off';
            app.TrainAccNum.Text = '0%';
            app.TestAccNum = uilabel(app.TrainTestIndicatorGridLayout);
            app.TestAccNum.Enable = 'off';
            app.TestAccNum.Text = '0%';
            app.TrainConfusionPushButton = uibutton(app.TrainTestIndicatorGridLayout, 'push');
            app.TrainConfusionPushButton.ButtonPushedFcn = createCallbackFcn(app, @TrainConfusionPushButtonPushed, true);
            app.TrainConfusionPushButton.Enable = 'off';
            app.TrainConfusionPushButton.Text = '查看';
            app.TestConfusionPushButton = uibutton(app.TrainTestIndicatorGridLayout, 'push');
            app.TestConfusionPushButton.ButtonPushedFcn = createCallbackFcn(app, @TestConfusionPushButtonPushed, true);
            app.TestConfusionPushButton.Enable = 'off';
            app.TestConfusionPushButton.Text = '查看';
            app.TrainROCPushButton = uibutton(app.TrainTestIndicatorGridLayout, 'push');
            app.TrainROCPushButton.ButtonPushedFcn = createCallbackFcn(app, @TrainROCPushButtonPushed, true);
            app.TrainROCPushButton.Enable = 'off';
            app.TrainROCPushButton.Text = '查看';
            app.TestROCPushButton = uibutton(app.TrainTestIndicatorGridLayout, 'push');
            app.TestROCPushButton.ButtonPushedFcn = createCallbackFcn(app, @TestROCPushButtonPushed, true);
            app.TestROCPushButton.Enable = 'off';
            app.TestROCPushButton.Text = '查看';
            app.TabGroup2 = uitabgroup(app.ImportDataPlotGridLayout);
            app.UserDataTab = uitab(app.TabGroup2);
            app.UserDataTab.Title = 'User0';
            app.UserDataTabGridLayout = uigridlayout(app.UserDataTab);
            app.UITable = uitable(app.UserDataTabGridLayout);
            app.UITable.ColumnName = {'Cycle Time'; 'Footstep'; 'Max Leg Speed'; 'Min Leg Speed'; 'Average Leg Speed'; 'Variance of Leg Speed'; 'Max Torso Speed'; 'Min Torso Speed'; 'Average Torso Speed'; 'Variance of Torso Speed'; '1st Magnitude-1'; '1st Magnitude-2'; '1st Magnitude-3'; '1st Magnitude-4'; '1st Magnitude-5'; '1st Magnitude-6'; '1st Magnitude-7'; '1st Magnitude-8'; '1st Magnitude-9'; '1st Magnitude-10'; '1st Magnitude-11'; '1st Magnitude-12'; '1st Magnitude-13'; '1st Magnitude-14'; '1st Magnitude-15'; '1st Magnitude-16'; '1st Magnitude-17'; '1st Magnitude-18'; '1st Magnitude-19'; '1st Magnitude-20'; '1st Magnitude-21'};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
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GaitUserID

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end